classdef demo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        SelectFileButton  matlab.ui.control.Button
    end

    methods (Access = private)

        % Button pushed function: SelectFileButton
        function SelectFileButtonPushed(app, event)
          
            [file,path] = uigetfile('*.png');
            
            in = imread([path,  file]);
            
            [L, G, globalX, globalK, LOrig, GOrig, optimalKSet] = computeGlobal();
            
            I = double(in(:)); % vectorize
            
            best = intmax;
            bestIndex = 0;
            
            for i = 1 : size(globalX, 2)
                if (  sum(abs(double(I) - double(globalX(:, i)))) < best )
                    bestIndex = i;
                    best = sum(abs(double(I) - double(globalX(:, i))));
                end
            end
            
            if (bestIndex ~= 0)
                iThink = reshape(globalX(:,bestIndex), 128, []);
                figure;
                montage({in, iThink});
                p = mod(i, 128); 
                p = ((2 * pi * p) / 128);
                title(['Actual vs Prediction, Angle: ', num2str(p)]);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.SelectFileButton = uibutton(app.UIFigure, 'push');
            app.SelectFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFileButtonPushed, true);
            app.SelectFileButton.Text = 'Select File';
        end
    end

    methods (Access = public)

        % Construct app
        function app = demo

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end